classdef ModelForHand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        LoadScoredROIMenu       matlab.ui.container.Menu
        LoadNewROIMenu          matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        MetricsMenu             matlab.ui.container.Menu
        CalculatefromROIMenu    matlab.ui.container.Menu
        LoadPeakMetricsMenu     matlab.ui.container.Menu
        DistributionMenu        matlab.ui.container.Menu
        PeakMetricsMenu         matlab.ui.container.Menu
        PeakScoreMenu           matlab.ui.container.Menu
        CorrelationMenu         matlab.ui.container.Menu
        GraphMenu               matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        RegressionTab           matlab.ui.container.Tab
        EditHiddenNeuron        matlab.ui.control.NumericEditField
        neuronsinHiddenLayerEditFieldLabel  matlab.ui.control.Label
        BtnMLPRegres            matlab.ui.control.Button
        BtnPLSRTrain            matlab.ui.control.Button
        BinaryClassTab          matlab.ui.container.Tab
        BtnMLPClass2            matlab.ui.control.Button
        BtnPLSDA2               matlab.ui.control.Button
        ClassSettingPanel_2     matlab.ui.container.Panel
        to2Label_2              matlab.ui.control.Label
        Class2Label_2           matlab.ui.control.Label
        EditClass2right2        matlab.ui.control.NumericEditField
        EditClass2left2         matlab.ui.control.NumericEditField
        EditClass1right2        matlab.ui.control.NumericEditField
        toEditFieldLabel_2      matlab.ui.control.Label
        EditClass1left2         matlab.ui.control.NumericEditField
        Class1EditFieldLabel_2  matlab.ui.control.Label
        TripleClassTab          matlab.ui.container.Tab
        BtnMLPClass3            matlab.ui.control.Button
        ClassSettingPanel       matlab.ui.container.Panel
        to3Label                matlab.ui.control.Label
        Class3Label             matlab.ui.control.Label
        to2Label                matlab.ui.control.Label
        Class2Label             matlab.ui.control.Label
        EditClass3right         matlab.ui.control.NumericEditField
        EditClass3left          matlab.ui.control.NumericEditField
        EditClass2right         matlab.ui.control.NumericEditField
        EditClass2left          matlab.ui.control.NumericEditField
        EditClass1right         matlab.ui.control.NumericEditField
        toEditFieldLabel        matlab.ui.control.Label
        EditClass1left          matlab.ui.control.NumericEditField
        Class1EditFieldLabel    matlab.ui.control.Label
        BtnPLSDA3               matlab.ui.control.Button
        NewTab                  matlab.ui.container.Tab
        BtnMLPClass3new         matlab.ui.control.Button
        BtnPLSDA3new            matlab.ui.control.Button
        BtnMLPClass2new         matlab.ui.control.Button
        BtnPLSDA2new            matlab.ui.control.Button
        BtnMLPRnew              matlab.ui.control.Button
        BtnPLSRnew              matlab.ui.control.Button
        ListROI                 matlab.ui.control.ListBox
        AxesScatter             matlab.ui.control.UIAxes
    end

    properties (Access = public)
        PLS_R  = []; % Description
        MLP_R  = [];
        PLS_C2 = [];
        PLS_C3 = [];        
        MLP_C2 = [];
        MLP_C3 = [];

        cvMeanX = [];
        cvStdX = [];
        dbMeanY = 0;
        dbThre_PLS2 =0;

        arrROI=[];
        nROI=0;
        matTrain=[];
        nTrain=0;
        
        arrTest=[];
        matTest=[];
        iCurTest = 1;
        nTest=0;
    end
    
    methods (Access = private)
        
        function app = DrawROI(app)
            cla(app.AxesScatter);
            if app.nTest==0,return;end
            plot(app.AxesScatter,app.arrTest{app.iCurTest}.cvPI,'LineWidth',2);
            title(app.AxesScatter,sprintf('Score = %3.2f',app.matTest(app.iCurTest,35)));
            xlabel(app.AxesScatter,'Data Point');
            ylabel(app.AxesScatter,'Intensity');
        end
        function app = UpdateListROI(app)
            app.ListROI.Items={''};
            if app.nTest==0,return;end
            cellStr = cell(app.nTest,1);
            for i=1:app.nTest
                cellStr{i} = sprintf('ROI%02d: score=%3.2f',i,app.matTest(i,35));
            end
            app.ListROI.Items=cellStr;
            app.ListROI.set('valueindex',app.iCurTest);
            clear cellStr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadScoredROIMenu
        function LoadScoredROIMenuSelected(app, event)
            [file,path] = uigetfile({'*.mat','Matlab Data File (*.mat)'});
            if file==0,return;end

            load([path,file],'arrPeak');
            app.arrROI = arrPeak;
            app.nROI  = length(arrPeak);
            clear arrPeak;
        end

        % Menu selected function: CalculatefromROIMenu
        function CalculatefromROIMenuSelected(app, event)
            if app.nROI==0
                msgbox('Please Load ROI first!');
                return;
            end
            subCalculateMetric(app);
            app.PLS_R  = []; 
            app.MLP_R  = [];
            app.PLS_C2 = [];
            app.PLS_C3 = [];        
            app.MLP_C2 = [];
            app.MLP_C3 = [];
        end

        % Menu selected function: LoadPeakMetricsMenu
        function LoadPeakMetricsMenuSelected(app, event)
            [file,path] = uigetfile({'*.mat','Matlab Data File (*.mat)'});
            if file==0,return;end

            load([path,file],'matScore');
            app.matTrain = matScore;
            app.nTrain   = size(matScore,1);
            
            app.PLS_R  = []; % Description
            app.MLP_R  = [];
            app.PLS_C2 = [];
            app.PLS_C3 = [];        
            app.MLP_C2 = [];
            app.MLP_C3 = [];
            
            app.cvMeanX = mean(app.matTrain(:,1:34));
            app.cvStdX = std(app.matTrain(:,1:34));
            app.dbMeanY = mean(app.matTrain(:,35));
            clear matScore;
        end

        % Menu selected function: LoadNewROIMenu
        function LoadNewROIMenuSelected(app, event)
            [file,path] = uigetfile({'*.mat','Matlab Data File (*.mat)'});
            if file==0,return;end
            load([path,file],'arrNew');
            app.arrTest = arrNew;
            app.nTest = length(arrNew);
            app.matTest = zeros(app.nTest,35);
            for i=1:app.nTest
                fPeak = funPeakScore(app.arrTest{i}.cvPI,[],app.arrTest{i}.nPoint);
                app.matTest(i,1:34) = fPeak.AllScore();
                app.matTest(i,35) = -1;
            end
            app.UpdateListROI();
            app.DrawROI();
            clear arrNew fPeak;
        end

        % Button pushed function: BtnPLSRTrain
        function BtnPLSRTrainButtonPushed(app, event)
            if app.nTrain==0,return;end
            Y = app.matTrain(:,35);
            X = app.matTrain(:,1:34);
            app.PLS_R = ModelPLS(zscore(X),Y-mean(Y),1);
            app.PLS_R.CompAutoFit();
            app.PLS_R.Q2Ycum();
            app.PLS_R.R2Y();
            figure('Visible','off');
            cla(app.AxesScatter);
            matCor = jet(128);
            for i=1:10
                scatter(app.AxesScatter,Y(Y==i/10),app.PLS_R.matScoreY(Y==i/10)+mean(Y),15,matCor(i*12-10,:));hold on
            end
            title(app.AxesScatter,sprintf('PLS regression, R2=%4.2f',sum(app.PLS_R.cvR2Y)));
            clear Y X matCor i;
        end

        % Button pushed function: BtnMLPRegres
        function BtnMLPRegresButtonPushed(app, event)
            if app.nTrain==0,return;end
            Y = app.matTrain(:,35)';
            X = zscore(app.matTrain(:,1:34))';

            % 创建一个具有两个隐藏层的BP神经网络
            hiddenLayerSizes = app.EditHiddenNeuron.Value;
            app.MLP_R = fitnet(hiddenLayerSizes);
            
            % 分割数据：70% 用于训练，15% 用于验证，15% 用于测试
            app.MLP_R.divideParam.trainRatio = 0.7;
            app.MLP_R.divideParam.valRatio = 0.15;
            app.MLP_R.divideParam.testRatio = 0.15;
            
            % 训练网络
            [app.MLP_R, ~] = train(app.MLP_R, X, Y);
            
            % 测试网络
            Y_pred = app.MLP_R(X);
            cla(app.AxesScatter);
            matCor = jet(128);
            for i=1:10
                scatter(app.AxesScatter,Y(Y==i/10),Y_pred(Y==i/10),15,matCor(i*12-10,:));hold on
            end
            R2 = 1 - sum((Y - Y_pred).*(Y - Y_pred))/sum((Y-mean(Y)).*(Y-mean(Y)));
            title(app.AxesScatter,sprintf('MLP regression, R2=%4.2f',R2));

            % figure;
            % plotperform(tr);
        end

        % Button pushed function: BtnPLSDA2
        function BtnPLSDA2ButtonPushed(app, event)
            if app.nTrain==0,return;end

            Y = app.matTrain(:,35);
            X = zscore(app.matTrain(:,1:34));
            Y(Y < app.EditClass1right.Value) = 0;
            Y(Y>0) = 1;
            
            app.PLS_C2 = ModelPLSDA(zscore(X),Y,1);
            app.PLS_C2.CompAutoFit();
            app.PLS_C2.Q2Ycum();
            app.PLS_C2.R2Y();

            dbMax =0;
            for dbThre = 0.1:0.01:0.8
                cvHit = (Y==1)& (app.PLS_C2.matScoreY(:,1)>=dbThre); % FN: db1
                db1 = sum(cvHit);
                cvHit = (Y==0)& (app.PLS_C2.matScoreY(:,1)<dbThre);  % FP: db2
                db2 = sum(cvHit);
                cvHit = (Y==1)& (app.PLS_C2.matScoreY(:,1)<dbThre);  % TP: db3
                db3=sum(cvHit);
                cvHit = (Y==0)& (app.PLS_C2.matScoreY(:,1)>=dbThre); % TN: db4
                db4=sum(cvHit);
                if dbMax<(db3+db4)/(db1+db2+db3+db4)
                    dbMax=(db3+db4)/(db1+db2+db3+db4);
                    FN = db1;FP=db2;TP=db3;TN=db4;
                    app.dbThre_PLS2 = dbThre;
                end
            end
            
            
            % 计算精度
            [X1,Y1,~,dbAUC] = perfcurve(Y,app.PLS_C2.matScoreY(:,1),1);
            if dbAUC<0.5
                dbAUC = 1 - dbAUC;
                Y1 = 1-Y1;
                X1 = 1-X1;
            end
            cla(app.AxesScatter);
            plot(app.AxesScatter,X1,Y1,'-r','linewidth',2);
            plot(app.AxesScatter,[0,1],[0,1],'--b','linewidth',1);
            ylabel(app.AxesScatter,'Sensitivity');
            xlabel(app.AxesScatter,'1-specificity');
            title(app.AxesScatter,sprintf('PLS-DA, ACC= %3.2f, AUC=%3.2f',dbMax,dbAUC));
            clear X1 Y1 dbAUC accuracy;


            hFig = figure(); % 获取整个窗体的句柄

            % 创建一个坐标轴区 patch 函数需要在坐标轴上画
            hAxes2 = uiaxes(hFig,'Position',[100,50,345,345],'Box', 'on','XTick',[],'YTick',[],...
                'Color',[0.8,0.8,0.8],'XLim',[0,1],'YLim',[0,1]);
            title(hAxes2,'Confusion Matrix');

            % 创建一个 patch
            line(hAxes2,[0,1],[1/3,1/3],'color','black');
            line(hAxes2,[0,1],[2/3,2/3],'color','black');
            line(hAxes2,[1/3,1/3],[0,1],'color','black');
            line(hAxes2,[2/3,2/3],[0,1],'color','black');
            patch(hAxes2,[0,2/3,2/3,0],[1/3,1/3,1,1],[0.8,0.8,1.0]);
            patch(hAxes2,[0,1/3,1/3,0],[2/3,2/3,1,1],[0.8,1.0,0.8]);
            patch(hAxes2,[1/3,2/3,2/3,1/3],[1/3,1/3,2/3,2/3],[0.8,1.0,0.8]);
            text(hAxes2,1/6-0.05,1/3+1/6,num2str(FN),'Color','red','FontSize',20);
            text(hAxes2,1/6-0.05,2/3+1/6,num2str(TN),'Color','blue','FontSize',20);
            text(hAxes2,1/6-0.05+1/3,1/3+1/6,num2str(TP),'Color','blue','FontSize',20);
            text(hAxes2,1/6-0.05+1/3,2/3+1/6,num2str(FP),'Color','red','FontSize',20);
            text(hAxes2,1/6-0.05+2/3,1/6,sprintf('%3.1f%%',100*dbMax),'Color','blue','FontSize',20);
            text(hAxes2,1/6-0.05,1/6,sprintf('%3.1f%%',100*(FN/(FN+TN))),'Color','red','FontSize',20);
            text(hAxes2,1/6+1/3-0.05,1/6,sprintf('%3.1f%%',100*(TP/(TP+FP))),'Color','red','FontSize',20);
            text(hAxes2,1/6+2/3-0.05,1/6+1/3,sprintf('%3.1f%%',100*(TP/(TP+FN))),'Color','red','FontSize',20);
            text(hAxes2,1/6+2/3-0.05,1/6+2/3,sprintf('%3.1f%%',100*(FP/(FP+TN))),'Color','red','FontSize',20);
            xlabel('True Score');
            ylabel('Predict Score');            
            drawnow

        end

        % Button pushed function: BtnMLPClass2
        function BtnMLPClass2ButtonPushed(app, event)
            if app.nTrain==0,return;end
            Y = app.matTrain(:,35)';
            X = zscore(app.matTrain(:,1:34))';
            Y(Y < app.EditClass1right.Value) = 0;
            Y(Y>0) = 1;

            hiddenLayerSize = app.EditHiddenNeuron.Value;
            app.MLP_C2 = patternnet(hiddenLayerSize);
            
            % 分割数据：70% 用于训练，15% 用于验证，15% 用于测试
            app.MLP_C2.divideParam.trainRatio = 0.7;
            app.MLP_C2.divideParam.valRatio  = 0.15;
            app.MLP_C2.divideParam.testRatio = 0.15;
            
            % 训练网络
            [app.MLP_C2, ~] = train(app.MLP_C2, X, Y);
            
            % 测试网络
            Y_pred = app.MLP_C2(X);
            
            % 将网络输出转换为二进制分类（0 或 1）
            Y_pred_classes = round(Y_pred);
            
            % 计算精度
            accuracy = sum(Y_pred_classes == Y) / numel(Y);
            
            [X1,Y1,~,dbAUC] = perfcurve(Y,Y_pred,1);
            if dbAUC<0.5
                dbAUC = 1 - dbAUC;
                Y1 = 1-Y1;
                X1 = 1-X1;
            end
            cla(app.AxesScatter);
            plot(app.AxesScatter,X1,Y1,'-r','linewidth',2);
            plot(app.AxesScatter,[0,1],[0,1],'--b','linewidth',1);
            ylabel(app.AxesScatter,'Sensitivity');
            xlabel(app.AxesScatter,'1-specificity');
            title(app.AxesScatter,sprintf('MLP, ACC= %3.2f, AUC=%3.2f',accuracy,dbAUC));
            clear X1 Y1 dbAUC accuracy;
        end

        % Button pushed function: BtnPLSDA3
        function BtnPLSDA3ButtonPushed(app, event)
            if app.nTrain==0,return;end
            cvY = app.matTrain(:,35);
            X = zscore(app.matTrain(:,1:34));
            
            nSample = length(cvY);
            Y = 2*ones(nSample,1);
            Y(cvY >=  app.EditClass3left.Value) = 1;
            Y(cvY <=  app.EditClass1right.Value) = 3;
            
            app.PLS_C3 = ModelPLSDA(zscore(X),Y,1);
            app.PLS_C3.CompAutoFit();
            app.PLS_C3.Q2Ycum();

            matScore = zeros(nSample,3);
            for i=1:nSample
                [~,iPos] = max(app.PLS_C3.matScoreY(i,:));
                matScore(i,iPos) = 1;
            end
            
            % 绘制ROC曲线  
            matC =[1 0 0;0 1 0; 0 0 1];
            cellLeg = cell(3,1);
            cellLeg{1} ='Class 1: ';
            cellLeg{2} ='Class 2: ';
            cellLeg{3} ='Class 3: ';
            
            cla(app.AxesScatter);
            h = zeros(3,1);
            
            accruacy = 0;
            for i=1:3
                temY = zeros(nSample,1);
                temY(Y==i)=1;
                accruacy = accruacy + sum(temY.*(matScore(:,i)==1));
                [X1,Y1,~,AUC] = perfcurve(temY, app.PLS_C3.matScoreY(:,i), 1);  
                if AUC<0.5
                    AUC = 1-AUC;
                    Y1 = 1-Y1;
                    X1 = 1-X1;
                end
                cellLeg{i} = [cellLeg{i},sprintf('AUC=%3.2f',AUC)];
                h(i)=plot(app.AxesScatter,X1, Y1,'linewidth',2,'Color',matC(i,:)); 
            end
            plot(app.AxesScatter,[0,1],[0,1],'--k');
            xlabel(app.AxesScatter,'False positive rate'); % X轴是假阳性率  
            ylabel(app.AxesScatter,'True positive rate'); % Y轴是真阳性率
            legend(app.AxesScatter,h,cellLeg);

            title(app.AxesScatter,sprintf('PLS, ACC= %3.2f',accruacy/5447));
            clear X1 Y1 AUC accuracy cellLeg temY;

            hFig = figure(); % 获取整个窗体的句柄

            % 创建一个坐标轴区 patch 函数需要在坐标轴上画
            hAxes2 = uiaxes(hFig,'Position',[100,50,345,345],'Box', 'on','XTick',[],'YTick',[],...
                'Color',[0.8,0.8,0.8],'XLim',[0,1],'YLim',[0,1]);
            title(hAxes2,'Confusion Matrix');

            % 创建一个 patch
            dbS = 1/4;
            patch(hAxes2,[0,3*dbS,3*dbS,0],[dbS,dbS,1,1],[0.8,0.8,1.0]);
            patch(hAxes2,[0,dbS,dbS,0],[3*dbS,3*dbS,1,1],[0.8,1.0,0.8]);
            patch(hAxes2,[dbS,2*dbS,2*dbS,dbS],[2*dbS,2*dbS,3*dbS,3*dbS],[0.8,1.0,0.8]);
            patch(hAxes2,[2*dbS,3*dbS,3*dbS,2*dbS],[dbS,dbS,2*dbS,2*dbS],[0.8,1.0,0.8]);

            line(hAxes2,[0,1],[dbS,dbS],'color','black');
            line(hAxes2,[0,1],[2*dbS,2*dbS],'color','black');
            line(hAxes2,[0,1],[3*dbS,3*dbS],'color','black');
            line(hAxes2,[dbS,dbS],[0,1],'color','black');
            line(hAxes2,[2*dbS,2*dbS],[0,1],'color','black');
            line(hAxes2,[3*dbS,3*dbS],[0,1],'color','black');

            
            nX = zeros(3,3);
            for i=1:3
                temY = zeros(nSample,1);
                temY(Y==i)=1;
                dbSum=0;
                for j=1:3
                    nX((i-1)*3+j) = sum(temY.*(matScore(:,j)>0));
                    text(hAxes2,(i-1)*dbS+0.5*dbS-0.05,(4-j)*dbS+0.5*dbS,num2str(nX((i-1)*3+j)),'Color','red','FontSize',20);
                    dbSum = dbSum + nX((i-1)*3+j);
                end
                text(hAxes2,(i-1)*dbS+0.5*dbS-0.05,0.5*dbS,sprintf('%3.1f%%',100*nX((i-1)*3+i)/dbSum),'Color','blue','FontSize',15);
            end
            for j=1:3
                text(hAxes2,3*dbS+0.5*dbS-0.05,(4-j+0.5)*dbS,sprintf('%3.1f%%',100*nX((j-1)*3+j)/sum(nX(j,:))),'Color','blue','FontSize',15);
            end
            text(hAxes2,3*dbS+0.5*dbS-0.08,0.5*dbS,sprintf('%3.1f%%',100*(nX(1,1)+nX(2,2)+nX(3,3))/sum(nX(:))),'Color','red','FontSize',20);
            xlabel('True Score');
            ylabel('Predict Score');            
            drawnow
        end

        % Button pushed function: BtnMLPClass3
        function BtnMLPClass3ButtonPushed(app, event)

            cvY = app.matTrain(:,35)';
            X = zscore(app.matTrain(:,1:34))';

            nSample = length(cvY);
            
            Y = ones(3,nSample); % 三分类问题，需要三个输出神经元
            Y(1,cvY <  app.EditClass3left.Value) = 0;
            Y(3,cvY >  app.EditClass1right.Value) = 0;
            Y(2,cvY > app.EditClass3left.Value) = 0;
            Y(2,cvY < app.EditClass1right.Value) = 0;

            hiddenLayerSize = app.EditHiddenNeuron.Value;
            app.MLP_C3 = patternnet(hiddenLayerSize);
            
            % 分割数据：70% 用于训练，15% 用于验证，15% 用于测试
            app.MLP_C3.divideParam.trainRatio = 0.7;
            app.MLP_C3.divideParam.valRatio  = 0.15;
            app.MLP_C3.divideParam.testRatio = 0.15;
            
            % 训练网络
            [app.MLP_C3, ~] = train(app.MLP_C3, X, Y);
            
            % 测试网络
            Y_pred = app.MLP_C3(X);
            
            % 将网络输出转换为二进制分类（0 或 1）
            Y_pred_classes = round(Y_pred);
            
            % 计算精度
            accuracy = sum(Y_pred_classes(:) == Y(:)) / numel(Y);

            % 绘制ROC曲线  
            matC =[1 0 0;0 1 0; 0 0 1];
            cellLeg = cell(3,1);
            cellLeg{1} ='Class 1: ';
            cellLeg{2} ='Class 2: ';
            cellLeg{3} ='Class 3: ';
            cla(app.AxesScatter);
            h = zeros(3,1);
            for i=1:3
                [X1,Y1,~,AUC] = perfcurve(Y(i,:), Y_pred(i,:), 1);  
                if AUC<0.5
                    AUC = 1-AUC;
                    Y1 = 1-Y1;
                    X1 = 1-X1;
                end
                cellLeg{i} = [cellLeg{i},sprintf('AUC=%3.2f',AUC)];
                h(i)=plot(app.AxesScatter,X1, Y1,'linewidth',2,'Color',matC(i,:)); 
            end
            xlabel(app.AxesScatter,'False positive rate'); % X轴是假阳性率  
            ylabel(app.AxesScatter,'True positive rate'); % Y轴是真阳性率
            plot(app.AxesScatter,[0,1],[0,1],'--k');
            legend(app.AxesScatter,h,cellLeg);
            
            title(app.AxesScatter,sprintf('MLP, ACC= %3.2f',accuracy));
            clear X1 Y1 AUC accuracy cellLeg;
        end

        % Value changed function: EditClass1right
        function EditClass1rightValueChanged(app, event)
            app.EditClass2left.Value = app.EditClass1right.Value;
        end

        % Value changed function: EditClass3left
        function EditClass3leftValueChanged(app, event)
            app.EditClass2right.Value = app.EditClass3left.Value;
        end

        % Value changed function: EditClass1right2
        function EditClass1right2ValueChanged(app, event)
            app.EditClass2left2.Value = app.EditClass1right2.Value;            
        end

        % Button pushed function: BtnPLSRnew
        function BtnPLSRnewButtonPushed(app, event)
            if app.nTest==0,return;end
            if isempty(app.PLS_R)
                msgbox('Please Train First!');
                return;
            end
            app.matTest(app.iCurTest,35) = app.PLS_R.PredictY(((app.matTest(app.iCurTest,1:34)-app.cvMeanX)./app.cvStdX))+app.dbMeanY;
            app.UpdateListROI();
            app.DrawROI();
        end

        % Button pushed function: BtnMLPRnew
        function BtnMLPRnewButtonPushed(app, event)
            if app.nTest==0,return;end
            if isempty(app.MLP_R)
                msgbox('Please Train First!');
                return;
            end
            app.matTest(app.iCurTest,35) = app.MLP_R(((app.matTest(app.iCurTest,1:34)-app.cvMeanX)./app.cvStdX)');
            app.UpdateListROI();
            app.DrawROI();
        end

        % Button pushed function: BtnPLSDA2new
        function BtnPLSDA2newButtonPushed(app, event)
            if app.nTest==0,return;end
            if isempty(app.PLS_C2)
                msgbox('Please Train First!');
                return;
            end
            dbScore = app.PLS_C2.PredictY(((app.matTest(app.iCurTest,1:34)-app.cvMeanX)./app.cvStdX));
            if dbScore>app.dbThre_PLS2
                app.matTest(app.iCurTest,35) =1;
            else
                app.matTest(app.iCurTest,35) =0;
            end
            app.UpdateListROI();
            app.DrawROI();
        end

        % Button pushed function: BtnMLPClass2new
        function BtnMLPClass2newButtonPushed(app, event)
            if app.nTest==0,return;end
            if isempty(app.MLP_C2)
                msgbox('Please Train First!');
                return;
            end
            app.matTest(app.iCurTest,35) = round(app.MLP_C2(((app.matTest(app.iCurTest,1:34)-app.cvMeanX)./app.cvStdX)'));
            app.UpdateListROI();
            app.DrawROI();
        end

        % Button pushed function: BtnPLSDA3new
        function BtnPLSDA3newButtonPushed(app, event)
            if app.nTest==0,return;end
            if isempty(app.PLS_C3)
                msgbox('Please Train First!');
                return;
            end
            cvScore = app.PLS_C3.PredictY(((app.matTest(app.iCurTest,1:34)-app.cvMeanX)./app.cvStdX));
            [~,app.matTest(app.iCurTest,35)] = max(cvScore);
            app.UpdateListROI();
            app.DrawROI();
        end

        % Button pushed function: BtnMLPClass3new
        function BtnMLPClass3newButtonPushed(app, event)
            if app.nTest==0,return;end
            if isempty(app.MLP_C3)
                msgbox('Please Train First!');
                return;
            end
            cvScore = app.MLP_C3(((app.matTest(app.iCurTest,1:34)-app.cvMeanX)./app.cvStdX)');
            [~,app.matTest(app.iCurTest,35)] = max(cvScore);
            app.UpdateListROI();
            app.DrawROI();
        end

        % Value changed function: ListROI
        function ListROIValueChanged(app, event)
            if app.nTest==0,return;end
            app.iCurTest = app.ListROI.get('valueindex');
            app.UpdateListROI();
            app.DrawROI();
        end

        % Menu selected function: PeakMetricsMenu
        function PeakMetricsMenuSelected(app, event)
            if app.nTrain==0,return;end
            cellStr = {'SNR';'CV';'ABR';'SYM';'GS';'TPAS';'H2B';'ZZ';'ΔZZ';'PCC';...
                'SHAR';'SKEW';'KURT';'RMS';'SE';'FFT.M';'FFT.SD';'WT.M';...
                'WT.SD';'CCR';'DM';'MOD';'ENT';'JAG';'NT';'SRA';'DENT';'ENT.S';'UNI';...
                'UNI.S';'ENERGY';'MEAN';'MED';'MAE'};
            figure();
            for i=1:34
                subplot(5,7,i);
                histogram(app.matTrain(:,i));
                title(cellStr{i});
            end
            clear cellStr i;
        end

        % Menu selected function: PeakScoreMenu
        function PeakScoreMenuSelected(app, event)
            if app.nTrain==0,return;end
            figure();
            histogram(app.matTrain(:,35),10);
            title('Histogram of peak score!');
        end

        % Menu selected function: GraphMenu
        function GraphMenuSelected(app, event)
            if app.nTrain==0,return;end
            cvCor = corr(app.matTrain(:,1:34),app.matTrain(:,35));
            matCorr = corr(app.matTrain(:,1:34));
            matCorr = (matCorr+matCorr')/2;
            matCorr = matCorr -diag(ones(1,34));
            matCorr(matCorr<0.35)=0;
            dg = graph(matCorr,{'SNR','CV','ABR','SYM','GS','TPAS','H2B','ZZ','ΔZZ',...
              'PCC','SHAR','SKEW','KURT','RMS','SE','FFT.M','FFT.SD','WT.M','WT.SD',...
              'CCR','DM','MOD','ENT','JAG','NT','SRA','DENT','ENT.S','UNI','UNI.S',...
              'ENERGY','MEAN','MED','MAE'});
            
            figure();
            cvNode= abs(cvCor);%[0.091,0.391,0.418,0.011,0.545,0.245,0.446,0.051,0.617,0.626,0.677,0.205];
            cvNode = (cvNode - min(cvNode))/max(cvNode)+0.05;
            cvLine = abs(dg.Edges.Weight);
            cvLine = (cvLine - min(cvLine))/max(cvLine);

            pe=plot(dg,'MarkerSize',18*sqrt(cvNode),'LineWidth',10*cvLine+0.01);%,'layout','forced');
            layout(pe,'force','UseGravity',true)
            % pe.EdgeLabel= round(dg.Edges.Weight*100)/100;
            % pe.EdgeColor = [0,0,0];
            
            pe.NodeLabelColor = [0,0,0];
            matColor = jet(256);
            cvColor = abs(cvCor);
            cvColor = (cvColor-min(cvColor))/max(cvColor);
            pe.NodeColor = matColor(ceil(cvColor*255)+1,:);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '   File';
            app.LoadScoredROIMenu = uimenu(app.FileMenu);
            app.LoadScoredROIMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadScoredROIMenuSelected, true);
            app.LoadScoredROIMenu.Text = 'Load Scored ROI';
            app.LoadNewROIMenu = uimenu(app.FileMenu);
            app.LoadNewROIMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNewROIMenuSelected, true);
            app.LoadNewROIMenu.Text = 'Load New  ROI';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.MetricsMenu = uimenu(app.UIFigure);
            app.MetricsMenu.Text = '   Metrics';
            app.CalculatefromROIMenu = uimenu(app.MetricsMenu);
            app.CalculatefromROIMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculatefromROIMenuSelected, true);
            app.CalculatefromROIMenu.Separator = 'on';
            app.CalculatefromROIMenu.Text = 'Calculate from ROI';
            app.LoadPeakMetricsMenu = uimenu(app.MetricsMenu);
            app.LoadPeakMetricsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPeakMetricsMenuSelected, true);
            app.LoadPeakMetricsMenu.Text = 'Load Peak Metrics';
            app.DistributionMenu = uimenu(app.UIFigure);
            app.DistributionMenu.Text = '   Distribution';
            app.PeakMetricsMenu = uimenu(app.DistributionMenu);
            app.PeakMetricsMenu.MenuSelectedFcn = createCallbackFcn(app, @PeakMetricsMenuSelected, true);
            app.PeakMetricsMenu.Text = 'Peak Metrics';
            app.PeakScoreMenu = uimenu(app.DistributionMenu);
            app.PeakScoreMenu.MenuSelectedFcn = createCallbackFcn(app, @PeakScoreMenuSelected, true);
            app.PeakScoreMenu.Text = 'Peak Score';
            app.CorrelationMenu = uimenu(app.UIFigure);
            app.CorrelationMenu.Text = '  Correlation';
            app.GraphMenu = uimenu(app.CorrelationMenu);
            app.GraphMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphMenuSelected, true);
            app.GraphMenu.Text = 'Graph';
            app.AxesScatter = uiaxes(app.UIFigure);
            xlabel(app.AxesScatter, 'True Score')
            ylabel(app.AxesScatter, 'Predict Score')
            zlabel(app.AxesScatter, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegressionTab = uitab(app.TabGroup);
            app.RegressionTab.Title = 'Regression';
            app.BtnPLSRTrain = uibutton(app.RegressionTab, 'push');
            app.BtnPLSRTrain.ButtonPushedFcn = createCallbackFcn(app, @BtnPLSRTrainButtonPushed, true);
            app.BtnPLSRTrain.Text = 'PLS Regress';
            app.BtnMLPRegres = uibutton(app.RegressionTab, 'push');
            app.BtnMLPRegres.ButtonPushedFcn = createCallbackFcn(app, @BtnMLPRegresButtonPushed, true);
            app.BtnMLPRegres.Text = 'MLP Regress';
            app.neuronsinHiddenLayerEditFieldLabel = uilabel(app.RegressionTab);
            app.neuronsinHiddenLayerEditFieldLabel.Text = 'neurons in Hidden Layer:';
            app.EditHiddenNeuron = uieditfield(app.RegressionTab, 'numeric');
            app.EditHiddenNeuron.Value = 8;
            app.BinaryClassTab = uitab(app.TabGroup);
            app.BinaryClassTab.Title = 'Binary Class';
            app.ClassSettingPanel_2 = uipanel(app.BinaryClassTab);
            app.ClassSettingPanel_2.Title = 'Class Setting';
            app.Class1EditFieldLabel_2 = uilabel(app.ClassSettingPanel_2);
            app.Class1EditFieldLabel_2.Text = 'Class 1';
            app.EditClass1left2 = uieditfield(app.ClassSettingPanel_2, 'numeric');
            app.EditClass1left2.Editable = 'off';
            app.EditClass1left2.Enable = 'off';
            app.toEditFieldLabel_2 = uilabel(app.ClassSettingPanel_2);
            app.toEditFieldLabel_2.Text = 'to';
            app.EditClass1right2 = uieditfield(app.ClassSettingPanel_2, 'numeric');
            app.EditClass1right2.ValueChangedFcn = createCallbackFcn(app, @EditClass1right2ValueChanged, true);
            app.EditClass1right2.Value = 0.35;
            app.EditClass2left2 = uieditfield(app.ClassSettingPanel_2, 'numeric');
            app.EditClass2left2.Editable = 'off';
            app.EditClass2left2.Enable = 'off';
            app.EditClass2left2.Value = 0.35;
            app.EditClass2right2 = uieditfield(app.ClassSettingPanel_2, 'numeric');
            app.EditClass2right2.Editable = 'off';
            app.EditClass2right2.Enable = 'off';
            app.EditClass2right2.Value = 1;
            app.Class2Label_2 = uilabel(app.ClassSettingPanel_2);
            app.Class2Label_2.Text = 'Class 2';
            app.to2Label_2 = uilabel(app.ClassSettingPanel_2);
            app.to2Label_2.Text = 'to';
            app.BtnPLSDA2 = uibutton(app.BinaryClassTab, 'push');
            app.BtnPLSDA2.ButtonPushedFcn = createCallbackFcn(app, @BtnPLSDA2ButtonPushed, true);
            app.BtnPLSDA2.Text = 'PLS Class-2';
            app.BtnMLPClass2 = uibutton(app.BinaryClassTab, 'push');
            app.BtnMLPClass2.ButtonPushedFcn = createCallbackFcn(app, @BtnMLPClass2ButtonPushed, true);
            app.BtnMLPClass2.Text = 'MLP Class-2';
            app.TripleClassTab = uitab(app.TabGroup);
            app.TripleClassTab.Title = 'Triple Class';
            app.BtnPLSDA3 = uibutton(app.TripleClassTab, 'push');
            app.BtnPLSDA3.ButtonPushedFcn = createCallbackFcn(app, @BtnPLSDA3ButtonPushed, true);
            app.BtnPLSDA3.Text = 'PLS Class-3';
            app.ClassSettingPanel = uipanel(app.TripleClassTab);
            app.ClassSettingPanel.Title = 'Class Setting';
            app.Class1EditFieldLabel = uilabel(app.ClassSettingPanel);
            app.Class1EditFieldLabel.Text = 'Class 1';
            app.EditClass1left = uieditfield(app.ClassSettingPanel, 'numeric');
            app.EditClass1left.Editable = 'off';
            app.EditClass1left.Enable = 'off';
            app.toEditFieldLabel = uilabel(app.ClassSettingPanel);
            app.toEditFieldLabel.Text = 'to';
            app.EditClass1right = uieditfield(app.ClassSettingPanel, 'numeric');
            app.EditClass1right.ValueChangedFcn = createCallbackFcn(app, @EditClass1rightValueChanged, true);
            app.EditClass1right.Value = 0.35;
            app.EditClass2left = uieditfield(app.ClassSettingPanel, 'numeric');
            app.EditClass2left.Editable = 'off';
            app.EditClass2left.Enable = 'off';
            app.EditClass2left.Value = 0.35;
            app.EditClass2right = uieditfield(app.ClassSettingPanel, 'numeric');
            app.EditClass2right.Editable = 'off';
            app.EditClass2right.Enable = 'off';
            app.EditClass2right.Value = 0.85;
            app.EditClass3left = uieditfield(app.ClassSettingPanel, 'numeric');
            app.EditClass3left.ValueChangedFcn = createCallbackFcn(app, @EditClass3leftValueChanged, true);
            app.EditClass3left.Value = 0.85;
            app.EditClass3right = uieditfield(app.ClassSettingPanel, 'numeric');
            app.EditClass3right.Editable = 'off';
            app.EditClass3right.Enable = 'off';
            app.EditClass3right.Value = 1;
            app.Class2Label = uilabel(app.ClassSettingPanel);
            app.Class2Label.Text = 'Class 2';
            app.to2Label = uilabel(app.ClassSettingPanel);
            app.to2Label.Text = 'to';
            app.Class3Label = uilabel(app.ClassSettingPanel);
            app.Class3Label.Text = 'Class 3';
            app.to3Label = uilabel(app.ClassSettingPanel);
            app.to3Label.Text = 'to';
            app.BtnMLPClass3 = uibutton(app.TripleClassTab, 'push');
            app.BtnMLPClass3.ButtonPushedFcn = createCallbackFcn(app, @BtnMLPClass3ButtonPushed, true);
            app.BtnMLPClass3.Text = 'MLP Class-3';
            app.NewTab = uitab(app.TabGroup);
            app.NewTab.Title = 'New';
            app.ListROI = uilistbox(app.NewTab);
            app.ListROI.Items = {'no ROI'};
            app.ListROI.ValueChangedFcn = createCallbackFcn(app, @ListROIValueChanged, true);
            app.ListROI.Value = 'no ROI';
            app.BtnPLSRnew = uibutton(app.NewTab, 'push');
            app.BtnPLSRnew.ButtonPushedFcn = createCallbackFcn(app, @BtnPLSRnewButtonPushed, true);
            app.BtnPLSRnew.Text = 'PLS Regress';
            app.BtnMLPRnew = uibutton(app.NewTab, 'push');
            app.BtnMLPRnew.ButtonPushedFcn = createCallbackFcn(app, @BtnMLPRnewButtonPushed, true);
            app.BtnMLPRnew.Text = 'MLP Regress';
            app.BtnPLSDA2new = uibutton(app.NewTab, 'push');
            app.BtnPLSDA2new.ButtonPushedFcn = createCallbackFcn(app, @BtnPLSDA2newButtonPushed, true);
            app.BtnPLSDA2new.Text = 'PLS Class-2';
            app.BtnMLPClass2new = uibutton(app.NewTab, 'push');
            app.BtnMLPClass2new.ButtonPushedFcn = createCallbackFcn(app, @BtnMLPClass2newButtonPushed, true);
            app.BtnMLPClass2new.Text = 'MLP Class-2';
            app.BtnPLSDA3new = uibutton(app.NewTab, 'push');
            app.BtnPLSDA3new.ButtonPushedFcn = createCallbackFcn(app, @BtnPLSDA3newButtonPushed, true);
            app.BtnPLSDA3new.Text = 'PLS Class-3';
            app.BtnMLPClass3new = uibutton(app.NewTab, 'push');
            app.BtnMLPClass3new.ButtonPushedFcn = createCallbackFcn(app, @BtnMLPClass3newButtonPushed, true);
            app.BtnMLPClass3new.Text = 'MLP Class-3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelForHand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end