classdef ModelForPicture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        LoadScoredROIMenu        matlab.ui.container.Menu
        LoadNewROIMenu           matlab.ui.container.Menu
        ExitMenu                 matlab.ui.container.Menu
        MetricsMenu              matlab.ui.container.Menu
        CalculatefromROIMenu     matlab.ui.container.Menu
        LoadPeakMetricsMenu      matlab.ui.container.Menu
        DistributionMenu         matlab.ui.container.Menu
        MetricsdistributionMenu  matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        TrainingTab              matlab.ui.container.Tab
        BtnMLPClass2             matlab.ui.control.Button
        BtnPLSDA2                matlab.ui.control.Button
        EditHiddenNeuron         matlab.ui.control.NumericEditField
        neuronsinHiddenLayerEditFieldLabel  matlab.ui.control.Label
        NewTab                   matlab.ui.container.Tab
        BtnMLPClass2new          matlab.ui.control.Button
        BtnPLSDA2new             matlab.ui.control.Button
        ListROI                  matlab.ui.control.ListBox
        AxesScatter              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        PLS_C2 = [];
        MLP_C2 = [];

        cvMeanX = [];
        cvStdX = [];
        dbMeanY = 0;
        dbThre_PLS2 =0;

        arrROI=[];
        nROI=0;
        matTrain=[];
        nTrain=0;
        
        arrTest=[];
        matTest=[];
        iCurTest = 1;
        nTest=0;
    end
    
    methods (Access = private)
        
        function app = DrawROI(app)
            cla(app.AxesScatter);
            if app.nTest==0,return;end
            plot(app.AxesScatter,app.arrTest{app.iCurTest}.cvPI,'LineWidth',2);
            if app.matTest(app.iCurTest,35)==0
                title(app.AxesScatter,'Low Quality Peak (Noise)');
            else
                title(app.AxesScatter,'High quality peak')
            end
            xlabel(app.AxesScatter,'Data Point');
            ylabel(app.AxesScatter,'Intensity');
        end
        function app = UpdateListROI(app)
            app.ListROI.Items={''};
            if app.nTest==0,return;end
            cellStr = cell(app.nTest,1);
            for i=1:app.nTest
                cellStr{i} = sprintf('ROI%02d: score=%3.2f',i,app.matTest(i,35));
            end
            app.ListROI.Items=cellStr;
            app.ListROI.set('valueindex',app.iCurTest);
            clear cellStr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadScoredROIMenu
        function LoadScoredROIMenuSelected(app, event)
            [file,path] = uigetfile({'*.mat','Matlab Data File (*.mat)'});
            if file==0,return;end

            load([path,file],'arrPeak');
            app.arrROI = arrPeak;
            app.nROI  = length(arrPeak);
            clear arrPeak;
        end

        % Menu selected function: CalculatefromROIMenu
        function CalculatefromROIMenuSelected(app, event)
            if app.nROI==0
                msgbox('Please Load ROI first!');
                return;
            end
            subCalculateMetric(app);
            app.PLS_C2 = [];
            app.MLP_C2 = [];
            
            app.matTrain(isnan(app.matTrain)) = 0;
            app.matTrain(isinf(app.matTrain)) = 0;
        end

        % Menu selected function: LoadPeakMetricsMenu
        function LoadPeakMetricsMenuSelected(app, event)
            [file,path] = uigetfile({'*.mat','Matlab Data File (*.mat)'});
            if file==0,return;end

            load([path,file],'matScore');
            app.matTrain = matScore;
            app.nTrain   = size(matScore,1);
            
            app.PLS_C2 = [];
            app.MLP_C2 = [];
            
            app.cvMeanX = mean(app.matTrain(:,1:34));
            app.cvStdX = std(app.matTrain(:,1:34));
            app.dbMeanY = mean(app.matTrain(:,35));
            clear matScore;
        end

        % Menu selected function: LoadNewROIMenu
        function LoadNewROIMenuSelected(app, event)
            [file,path] = uigetfile({'*.mat','Matlab Data File (*.mat)'});
            if file==0,return;end
            load([path,file],'arrNew');
            app.arrTest = arrNew;
            app.nTest = length(arrNew);
            app.matTest = zeros(app.nTest,35);
            for i=1:app.nTest
                fPeak = funPeakScore(app.arrTest{i}.cvPI,[],app.arrTest{i}.nPoint);
                app.matTest(i,1:34) = fPeak.AllScore();
                app.matTest(i,35) = -1;
            end
            app.UpdateListROI();
            app.DrawROI();
            clear arrNew fPeak;
        end

        % Button pushed function: BtnPLSDA2
        function BtnPLSDA2ButtonPushed(app, event)
            if app.nTrain==0,return;end

            Y = app.matTrain(:,35);
            X = zscore(app.matTrain(:,1:34));
            
            app.PLS_C2 = ModelPLSDA(zscore(X),Y,1);
            app.PLS_C2.CompAutoFit();
            app.PLS_C2.Q2Ycum();
            app.PLS_C2.R2Y();

            dbMax =0;
            for dbThre = 0.1:0.001:0.28
                cvHit = (Y==1)& (app.PLS_C2.matScoreY(:,1)>=dbThre);
                db1 = sum(cvHit);
                cvHit = (Y==0)& (app.PLS_C2.matScoreY(:,1)<dbThre);
                db2 = sum(cvHit);
                cvHit = (Y==1)& (app.PLS_C2.matScoreY(:,1)<dbThre);
                db3=sum(cvHit);
                cvHit = (Y==0)& (app.PLS_C2.matScoreY(:,1)>=dbThre);
                db4=sum(cvHit);
                if dbMax<(db3+db4)/(db1+db2+db3+db4)
                    dbMax=(db3+db4)/(db1+db2+db3+db4);
                    app.dbThre_PLS2 = dbThre;
                end
            end
            
            % 计算精度
            [X1,Y1,~,dbAUC] = perfcurve(Y,app.PLS_C2.matScoreY(:,1),1);
            if dbAUC<0.5
                dbAUC = 1 - dbAUC;
                Y1 = 1-Y1;
                X1 = 1-X1;
            end
            cla(app.AxesScatter);
            plot(app.AxesScatter,X1,Y1,'-r','linewidth',2);
            plot(app.AxesScatter,[0,1],[0,1],'--b','linewidth',1);
            ylabel(app.AxesScatter,'Sensitivity');
            xlabel(app.AxesScatter,'1-specificity');
            title(app.AxesScatter,sprintf('PLS-DA, ACC= %3.2f, AUC=%3.2f',dbMax,dbAUC));
            clear X1 Y1 dbAUC accuracy;
        end

        % Button pushed function: BtnMLPClass2
        function BtnMLPClass2ButtonPushed(app, event)
            if app.nTrain==0,return;end
            Y = app.matTrain(:,35)';
            X = zscore(app.matTrain(:,1:34))';

            hiddenLayerSize = app.EditHiddenNeuron.Value;
            app.MLP_C2 = patternnet(hiddenLayerSize);
            
            % 分割数据：70% 用于训练，15% 用于验证，15% 用于测试
            app.MLP_C2.divideParam.trainRatio = 0.7;
            app.MLP_C2.divideParam.valRatio  = 0.15;
            app.MLP_C2.divideParam.testRatio = 0.15;
            
            % 训练网络
            [app.MLP_C2, ~] = train(app.MLP_C2, X, Y);
            
            % 测试网络
            Y_pred = app.MLP_C2(X);
            
            % 将网络输出转换为二进制分类（0 或 1）
            Y_pred_classes = round(Y_pred);
            
            % 计算精度
            accuracy = sum(Y_pred_classes == Y) / numel(Y);
            
            [X1,Y1,~,dbAUC] = perfcurve(Y,Y_pred,1);
            if dbAUC<0.5
                dbAUC = 1 - dbAUC;
                Y1 = 1-Y1;
                X1 = 1-X1;
            end
            cla(app.AxesScatter);
            plot(app.AxesScatter,X1,Y1,'-r','linewidth',2);
            plot(app.AxesScatter,[0,1],[0,1],'--b','linewidth',1);
            ylabel(app.AxesScatter,'Sensitivity');
            xlabel(app.AxesScatter,'1-specificity');
            title(app.AxesScatter,sprintf('MLP, ACC= %3.2f, AUC=%3.2f',accuracy,dbAUC));
            clear X1 Y1 dbAUC accuracy;
        end

        % Menu selected function: MetricsdistributionMenu
        function MetricsdistributionMenuSelected(app, event)
            if app.nTrain==0,return;end
            cellStr = {'SNR';'CV';'ABR';'SYM';'GS';'TPAS';'H2B';'ZZ';'ΔZZ';'PCC';...
                'SHAR';'SKEW';'KURT';'RMS';'SE';'FFT.M';'FFT.SD';'WT.M';...
                'WT.SD';'CCR';'DM';'MOD';'ENT';'JAG';'NT';'SRA';'DENT';'ENT.S';'UNI';...
                'UNI.S';'ENERGY';'MEAN';'MED';'MAE'};
            figure();
            for i=1:34
                subplot(5,7,i);
                histogram(app.matTrain(:,i));
                title(cellStr{i});
            end
            clear cellStr i;
        end

        % Value changed function: ListROI
        function ListROIValueChanged(app, event)
            if app.nTest==0,return;end
            app.iCurTest = app.ListROI.get('valueindex');
            app.UpdateListROI();
            app.DrawROI();
        end

        % Button pushed function: BtnPLSDA2new
        function BtnPLSDA2newButtonPushed(app, event)
            if app.nTest==0,return;end
            if isempty(app.PLS_C2)
                msgbox('Please Train First!');
                return;
            end
            dbScore = app.PLS_C2.PredictY(((app.matTest(app.iCurTest,1:34)-app.cvMeanX)./app.cvStdX));
            if dbScore>app.dbThre_PLS2
                app.matTest(app.iCurTest,35) =1;
            else
                app.matTest(app.iCurTest,35) =0;
            end
            app.UpdateListROI();
            app.DrawROI();
        end

        % Button pushed function: BtnMLPClass2new
        function BtnMLPClass2newButtonPushed(app, event)
            if app.nTest==0,return;end
            if isempty(app.MLP_C2)
                msgbox('Please Train First!');
                return;
            end
            app.matTest(app.iCurTest,35) = round(app.MLP_C2(((app.matTest(app.iCurTest,1:34)-app.cvMeanX)./app.cvStdX)'));
            app.UpdateListROI();
            app.DrawROI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '  File';
            app.LoadScoredROIMenu = uimenu(app.FileMenu);
            app.LoadScoredROIMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadScoredROIMenuSelected, true);
            app.LoadScoredROIMenu.Text = 'Load Scored ROI';
            app.LoadNewROIMenu = uimenu(app.FileMenu);
            app.LoadNewROIMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNewROIMenuSelected, true);
            app.LoadNewROIMenu.Text = 'Load New ROI';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.MetricsMenu = uimenu(app.UIFigure);
            app.MetricsMenu.Text = '  Metrics';
            app.CalculatefromROIMenu = uimenu(app.MetricsMenu);
            app.CalculatefromROIMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculatefromROIMenuSelected, true);
            app.CalculatefromROIMenu.Separator = 'on';
            app.CalculatefromROIMenu.Text = 'Calculate from ROI';
            app.LoadPeakMetricsMenu = uimenu(app.MetricsMenu);
            app.LoadPeakMetricsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPeakMetricsMenuSelected, true);
            app.LoadPeakMetricsMenu.Text = 'Load Peak Metrics';
            app.DistributionMenu = uimenu(app.UIFigure);
            app.DistributionMenu.Text = '  Distribution';
            app.MetricsdistributionMenu = uimenu(app.DistributionMenu);
            app.MetricsdistributionMenu.MenuSelectedFcn = createCallbackFcn(app, @MetricsdistributionMenuSelected, true);
            app.MetricsdistributionMenu.Text = 'Metrics distribution';
            app.AxesScatter = uiaxes(app.UIFigure);
            xlabel(app.AxesScatter, 'True Score')
            ylabel(app.AxesScatter, 'Predict Score')
            zlabel(app.AxesScatter, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.neuronsinHiddenLayerEditFieldLabel = uilabel(app.TrainingTab);
            app.neuronsinHiddenLayerEditFieldLabel.Text = 'neurons in Hidden Layer:';
            app.EditHiddenNeuron = uieditfield(app.TrainingTab, 'numeric');
            app.EditHiddenNeuron.Value = 8;
            app.BtnPLSDA2 = uibutton(app.TrainingTab, 'push');
            app.BtnPLSDA2.ButtonPushedFcn = createCallbackFcn(app, @BtnPLSDA2ButtonPushed, true);
            app.BtnPLSDA2.Text = 'PLS-DA';
            app.BtnMLPClass2 = uibutton(app.TrainingTab, 'push');
            app.BtnMLPClass2.ButtonPushedFcn = createCallbackFcn(app, @BtnMLPClass2ButtonPushed, true);
            app.BtnMLPClass2.Text = 'MLP';
            app.NewTab = uitab(app.TabGroup);
            app.NewTab.Title = 'New';
            app.ListROI = uilistbox(app.NewTab);
            app.ListROI.Items = {'no ROI'};
            app.ListROI.ValueChangedFcn = createCallbackFcn(app, @ListROIValueChanged, true);
            app.ListROI.Value = 'no ROI';
            app.BtnPLSDA2new = uibutton(app.NewTab, 'push');
            app.BtnPLSDA2new.ButtonPushedFcn = createCallbackFcn(app, @BtnPLSDA2newButtonPushed, true);
            app.BtnPLSDA2new.Text = 'PLS-DA';
            app.BtnMLPClass2new = uibutton(app.NewTab, 'push');
            app.BtnMLPClass2new.ButtonPushedFcn = createCallbackFcn(app, @BtnMLPClass2newButtonPushed, true);
            app.BtnMLPClass2new.Text = 'MLP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelForPicture

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end