classdef Scoring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MenuFile             matlab.ui.container.Menu
        MenuFilieLoad        matlab.ui.container.Menu
        MenuFileSave         matlab.ui.container.Menu
        MenuFileExit         matlab.ui.container.Menu
        ListROI              matlab.ui.control.ListBox
        BtnNext              matlab.ui.control.Button
        EditScore            matlab.ui.control.NumericEditField
        ScoreEditFieldLabel  matlab.ui.control.Label
        AxesROI              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arrROI; % Description
        nROI=0;
        iCurROI = 1;
    end
    
    methods (Access = private)
        
        function app = DrawROI(app)
            if app.nROI==0,return;end
            plot(app.AxesROI,app.arrROI{app.iCurROI}.cvPI);
        end
        function app = UpdateListBox(app)
            if app.nROI==0
                app.ListROI.Items ={'No ROI'};
                return;
            end
            cellStr = cell(app.nROI,1);
            for i=1:app.nROI
                cellStr{i} = sprintf('%03d: s=%2.1f',i,app.arrROI{i}.dbScore);
            end
            app.ListROI.Items = cellStr;
            clear cellStr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: MenuFilieLoad
        function MenuFilieLoadSelected(app, event)
            [file,path] = uigetfile({'*.mat','Matlab Data File (*.mat)'});
            if file==0,return;end

            load([path file],'arrPeak');
            app.arrROI = arrPeak;
            app.nROI = length(arrPeak);
            cellStr = cell(app.nROI,1);
            for i=1:app.nROI
                cellStr{i} = sprintf('%03d: s=%2.1f',i,arrPeak{i}.dbScore);
            end
            app.ListROI.Items = cellStr;
            clear cellStr;
            app.iCurROI =1;
            app.ListROI.set('valueindex',app.iCurROI);
            app.EditScore.Value = app.arrROI{app.iCurROI}.dbScore;
            clear arrPeak;
            app.DrawROI();


        end

        % Button pushed function: BtnNext
        function BtnNextButtonPushed(app, event)
            app.arrROI{app.iCurROI}.dbScore = app.EditScore.Value;
            app.UpdateListBox();
            app.iCurROI = app.iCurROI +1;
            if app.iCurROI>app.nROI
                app.iCurROI=app.nROI;
            end
            app.EditScore.Value = app.arrROI{app.iCurROI}.dbScore;
            app.DrawROI();
            app.EditScore.focus();
            app.ListROI.set('valueindex',app.iCurROI);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if isequal(key,'return')
                BtnNextButtonPushed(app,event);
            end
        end

        % Menu selected function: MenuFileExit
        function MenuFileExitSelected(app, event)
            delete(app);
        end

        % Menu selected function: MenuFileSave
        function MenuFileSaveSelected(app, event)
            if app.nROI==0,return;end
            
            [file,path] = uigetfile({'*.mat','Matlab Data File (*.mat)'});
            if file==0,return;end
            appPeak = app.arrROI;
            save([path file],'appPeak');
            clear appPeak file path;
        end

        % Value changed function: ListROI
        function ListROIValueChanged(app, event)
            if app.nROI==0,return;end
            app.iCurROI = app.ListROI.get('valueindex');
            app.EditScore.Value = app.arrROI{app.iCurROI}.dbScore;
            app.DrawROI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.MenuFile = uimenu(app.UIFigure);
            app.MenuFile.Text = '  File';
            app.MenuFilieLoad = uimenu(app.MenuFile);
            app.MenuFilieLoad.MenuSelectedFcn = createCallbackFcn(app, @MenuFilieLoadSelected, true);
            app.MenuFilieLoad.Text = 'Load';
            app.MenuFileSave = uimenu(app.MenuFile);
            app.MenuFileSave.MenuSelectedFcn = createCallbackFcn(app, @MenuFileSaveSelected, true);
            app.MenuFileSave.Text = 'Save';
            app.MenuFileExit = uimenu(app.MenuFile);
            app.MenuFileExit.MenuSelectedFcn = createCallbackFcn(app, @MenuFileExitSelected, true);
            app.MenuFileExit.Separator = 'on';
            app.MenuFileExit.Text = 'Exit';
            app.AxesROI = uiaxes(app.UIFigure);
            xlabel(app.AxesROI, 'Retention Time')
            ylabel(app.AxesROI, 'Intensity')
            zlabel(app.AxesROI, 'Z')
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.EditScore = uieditfield(app.UIFigure, 'numeric');
            app.BtnNext = uibutton(app.UIFigure, 'push');
            app.BtnNext.ButtonPushedFcn = createCallbackFcn(app, @BtnNextButtonPushed, true);
            app.BtnNext.Text = 'Next';
            app.ListROI = uilistbox(app.UIFigure);
            app.ListROI.Items = {'no ROI'};
            app.ListROI.ValueChangedFcn = createCallbackFcn(app, @ListROIValueChanged, true);
            app.ListROI.Value = 'no ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scoring

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end