classdef subCalculateMetric < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Status     matlab.ui.control.Label
        BtnCancel  matlab.ui.control.Button
        BtnOK      matlab.ui.control.Button
    end

    
    properties (Access = private)
        appMain; % Description
        dbPct = 0.0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appMain)
            app.appMain = appMain;
        end

        % Button pushed function: BtnOK
        function BtnOKPushed(app, event)
            app.Status.Text = 'Calculating ...';

            hFig = app.UIFigure; % 获取整个窗体的句柄

            % 创建一个坐标轴区 patch 函数需要在坐标轴上画
            hAxes2 = uiaxes(hFig,'Position',[50,120,345,55],'Box', 'on','XTick',[],'YTick',[],...
                'Color',[0.8,0.8,0.8],'XLim',[0,1],'YLim',[0,1]);

            title(hAxes2,sprintf('Total Progress 1/%d',app.appMain.nROI));

            % 创建一个 patch
            hPatch2 = patch(hAxes2,[0,0,0,0],[0,0,1,1],[0.6,0.8,1.0]);

            hPatch2.XData = [0,0,0,0];
            drawnow;

            app.appMain.matTrain = zeros(app.appMain.nROI,35);
            app.appMain.nTrain = app.appMain.nROI;
            for i=1:app.appMain.nROI

                fPeak = funPeakScore(app.appMain.arrROI{i}.cvPI,[],app.appMain.arrROI{i}.nPoint);
                cvScore = fPeak.AllScore();
                app.appMain.matTrain(i,1:34) = cvScore;
                app.appMain.matTrain(i,35) = app.appMain.arrROI{i}.dbScore;

                if (i/30)==floor(i/30)
                    title(hAxes2,sprintf('total progress %d/%d',i,app.appMain.nROI)); 
                    hPatch2.XData = [0,(i-1)/app.appMain.nROI,(i-1)/app.appMain.nROI,0];
                    drawnow;
                end
            end
            
            title(hAxes2,sprintf('total progress %d/%d',i,app.appMain.nROI)); 
            hPatch2.XData = [0,(i-1)/app.appMain.nROI,(i-1)/app.appMain.nROI,0];
            drawnow;

            app.appMain.cvMeanX = mean(app.appMain.matTrain(:,1:34));
            app.appMain.cvStdX = std(app.appMain.matTrain(:,1:34));
            app.appMain.dbMeanY = mean(app.appMain.matTrain(:,35));
            clear cvScore;

            app.Status.Text = 'Calculation Completed!';
        end

        % Button pushed function: BtnCancel
        function BtnCancelPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BtnOK = uibutton(app.UIFigure, 'push');
            app.BtnOK.ButtonPushedFcn = createCallbackFcn(app, @BtnOKPushed, true);
            app.BtnOK.Text = 'Calculate';
            app.BtnCancel = uibutton(app.UIFigure, 'push');
            app.BtnCancel.ButtonPushedFcn = createCallbackFcn(app, @BtnCancelPushed, true);
            app.BtnCancel.Text = 'Exit';
            app.Status = uilabel(app.UIFigure);
            app.Status.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subCalculateMetric(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end